classdef SingleUnits_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        uibuttongroup3                 matlab.ui.container.ButtonGroup
        LFPaxes                        matlab.ui.control.UIAxes
        SUaxes                         matlab.ui.control.UIAxes
        checkbox_plotLFP               matlab.ui.control.CheckBox
        checkbox_SUmax                 matlab.ui.control.CheckBox
        checkbox_SUmin                 matlab.ui.control.CheckBox
        pushbutton_scroll_left         matlab.ui.control.Button
        pushbutton_reset               matlab.ui.control.Button
        pushbutton_scroll_right        matlab.ui.control.Button
        edit_tStart_plot               matlab.ui.control.EditField
        edit_tEnd_plot                 matlab.ui.control.EditField
        ReplotButton                   matlab.ui.control.Button
        HPaxes                         matlab.ui.control.UIAxes
        checkbox_hp                    matlab.ui.control.CheckBox
        uibuttongroup1                 matlab.ui.container.ButtonGroup
        text2                          matlab.ui.control.Label
        text4                          matlab.ui.control.Label
        text5                          matlab.ui.control.Label
        label_nMax                     matlab.ui.control.Label
        label_nMin                     matlab.ui.control.Label
        calculate_SU                   matlab.ui.control.Button
        reset_SU                       matlab.ui.control.Button
        text5_2                        matlab.ui.control.Label
        label_nTot                     matlab.ui.control.Label
        HPhisto_bt                     matlab.ui.control.Button
        trNumber                       matlab.ui.control.Spinner
        trNumberTxt                    matlab.ui.control.Label
        chNumber                       matlab.ui.control.Spinner
        chNumberTxt                    matlab.ui.control.Label
        BandPassLabel                  matlab.ui.control.Label
        BpSelection                    matlab.ui.control.Spinner
        ThresholdmultiplierEditFieldLabel  matlab.ui.control.Label
        ThresholdmultiplierEditField   matlab.ui.control.NumericEditField
        RefractoryperiodmsEditFieldLabel  matlab.ui.control.Label
        RefractoryperiodmsEditField    matlab.ui.control.NumericEditField
        uibuttongroup4                 matlab.ui.container.ButtonGroup
        checkbox_calculateSUUPDOWN     matlab.ui.control.CheckBox
        uitable_output                 matlab.ui.control.Table
        checkbox_savetimes             matlab.ui.control.CheckBox
        edit_ltpnumber                 matlab.ui.control.EditField
        text8                          matlab.ui.control.Label
        OnlyworksonchannelandtrialselectedinZebraLabel  matlab.ui.control.Label
        CopyTableButton                matlab.ui.control.Button
        RasterplotPanel                matlab.ui.container.Panel
        rasterPlot_bt                  matlab.ui.control.Button
        KernelbandwidthEditFieldLabel  matlab.ui.control.Label
        kernelBW_edit                  matlab.ui.control.NumericEditField
        detectionPlot_ck               matlab.ui.control.CheckBox
    end

% Comment by Didi: Notes on how to use the program.
% 1) Single Units extraction was done according to Quiroga et al. In order
% to stick to the recommendations in the paper, use high pass filtered data
% of 300 Hz set in the ZebraExplore main program.

% 2) Set the channel and sampling period yourself: the single unit program
% uses the channel and sampling period as set by the user here, NOT the
% ones set in the main ZebraExplore figure

% 3) This program uses results from the EventDetection program. To use
% this, you must have computed in EventDetection first. Note that
% all information used here from EventDetection is the start and end time
% of up and down states. Depending on your goal, you can therefore select
% on which channel you want to identify up and down states by setting the
% channel in EventDetection. It is recommended to use the channel with the clearest up states to
% detect up and down state times in EventDetection, and then keep these times
% for both channel calculations in the single units program

% 4) The order of execution is important: always click calculate in the SU
% box on top first, only then you can plot and/or calculate in up and down
% states

% 5) To save the timing of positive and negative single units, as well as
% of up and downs states, click the save times button, and make sure the
% lpt number is set correctly is you used a sum (more than 1) of recordings
% at the ephys setup   

    properties (Access = private)
        
    end
    
    properties (Access = public)
        parentApp % ZebraMain handle
        minAt %indices of negative peaks
        maxAt
        nus
        nds
        us_startI
        us_endI
        ds_startI
        ds_endI
        neg_su_in_us
        neg_su_in_ds
        pos_su_in_us
        pos_su_in_ds
        LFP_hp
        x
        nMax
        nMin
        time
        x_max_time
        x_max_index
        x_min_time
        x_min_index
        thr
        fireRate_smooth
        datatable
    end
    
    methods (Access = private)
        function findpeaks(app)
            % function to identify number and timing of positively and negatively
            % deflecting peaks
            
            % let's start by creating vectors contaning the time points and the high
            % pass filtered data
            
            app.LFP_hp = app.parentApp.bandPassed_LFP(app.BpSelection.Value,:,:,:); % we now have the high pass filtered data in a 3D matrix
            [~,nch,ntr,l] = size(app.LFP_hp);
            app.LFP_hp = reshape(app.LFP_hp,nch,ntr,l); % remove BP singleton maintaing all other possible singletons
            % Now find a threshold for every channel, as proposed by Quiroga et al.
            thr = zeros(1,app.parentApp.nCh); %stores the calculated threshold for each channel
            for i=1:app.parentApp.nCh
                tmp = squeeze(app.LFP_hp(i,:,:)); %tmp is a 2D matrix with dimension trials and time.
                sigma = median(abs(tmp(:))/0.6745); %the median is calculated over all the channel timepoints
                thr(i) = app.ThresholdmultiplierEditField.Value*sigma;
            end
            
%             if app.detectionPlot_ck.Value
%                 t = (0:app.parentApp.dtaLen-1)*app.parentApp.sp; %time vector
%                 ch = app.chNumber.Value;
%                 tr = app.trNumber.Value;
%                 figure
%                 ax = axes;
%                 hold(ax,'on')
%                 plot(ax,t,squeeze(app.LFP_hp(ch,tr,:)),'k')
%                 plot(ax,ax.XLim,[thr(ch),thr(ch)],'Color','r')
%                 plot(ax,ax.XLim,[-thr(ch),-thr(ch)],'Color','b')
%                 ax.XLabel.String = 'Time (s)';
%                 ax.YLabel.String = 'Voltage ( )';
%                 ax.Title.String = ['Ch ' num2str(ch) ',  Tr ' num2str(tr)]; 
%             end
            
            % identify the peaks higher than threshold
            len = app.parentApp.dtaLen;
            app.x = 1:len;
            
            % setting the to-be-calculated values/matrices to find positive and negative peaks
            app.nMax = zeros(app.parentApp.nCh,app.parentApp.nTrials);
            app.maxAt = cell(app.parentApp.nCh,app.parentApp.nTrials);
            
            app.nMin = zeros(app.parentApp.nCh,app.parentApp.nTrials);
            app.minAt = cell(app.parentApp.nCh,app.parentApp.nTrials);
            
            
            % we can now look at episodes above threshold
            for iCh = 1:app.parentApp.nCh             % loop on channels
                for iTr = 1:app.parentApp.nTrials     % loop on trials
                    for j = 1:len                     % loop on time points
                        
                        if j == 1 %fist time point
                            if app.LFP_hp(iCh,iTr,j) >= thr(iCh) % for the first time point, if it is higher than threshold it is the first peak
                                app.nMax(iCh,iTr) = app.nMax(iCh,iTr) + 1; %increase the peak number by one
                                app.maxAt{iCh,iTr}(app.nMax(iCh,iTr)) = app.x(j); % use this index as the starting index of the current peak
                                
                            elseif app.LFP_hp(iCh,iTr,j) <= -thr(iCh) %the same for downward peaks
                                app.nMin(iCh,iTr) = app.nMin(iCh,iTr) + 1;
                                app.minAt{iCh,iTr}(app.nMin(iCh,iTr)) = app.x(j);
                            end
                        elseif j > 1
                            %again, look for positive peak first
%                             if app.LFP_hp(iCh,iTr,j) >= thr(iCh) && app.LFP_hp(iCh,iTr,j-1) < thr(iCh) % we test that it is the first time point higher than threshold
                            if app.LFP_hp(iCh,iTr,j) >= thr(iCh)    
                                app.nMax(iCh,iTr) = app.nMax(iCh,iTr) + 1; % then we count it as a new peak
                                app.maxAt{iCh,iTr}(app.nMax(iCh,iTr)) = app.x(j); % and label its starting index
                            %and then for negative peaks
%                             elseif app.LFP_hp(iCh,iTr,j) <= -thr(iCh) && app.LFP_hp(iCh,iTr,j-1) > -thr(iCh)
                            elseif app.LFP_hp(iCh,iTr,j) <= -thr(iCh)    
                                app.nMin(iCh,iTr) = app.nMin(iCh,iTr) + 1; 
                                app.minAt{iCh,iTr}(app.nMin(iCh,iTr)) = app.x(j);
                            end
                        end
                        
                    end
                end
            end
            app.thr = thr;
        end
        
        function Extract_SWA(app)
            % function to extract the indexes of up and down states
            
            % first obtained the start and end points of the up states obtained with
            % EventDetection. Choose in EventDetection from which channel you want to
            % identify up states.
            
            % the fromT toT values in the upstates list of evDetection is longer than
            % NUS and NDS (this happens during fusing/removal up states)..these last
            % fromT and toT values contains the last up state time and so if I would
            % include these I would count several single units several times. To
            % prevent this, I first find the correct value of number of up and down
            % states and then extract only fromT and toT of this length
            app.nus = app.parentApp.evDet.NUS;
            app.nds = app.parentApp.evDet.NDS;
            us_start = (app.parentApp.evDet.upstates.fromT(1:app.nus))';
            us_end = (app.parentApp.evDet.upstates.toT(1:app.nus))';
            ds_start = (app.parentApp.evDet.downstates.fromT(1:app.nds))';
            ds_end = (app.parentApp.evDet.downstates.toT(1:app.nds))';
            % test if each start point also has an end point, i.e. if the data are
            % correct
            if length(us_start) ~= length(us_end) || length(ds_start) ~= length(ds_end)
                error('input data from EvDet do not have correct lengths')
            end
            
            % Then transform the time points to index point
            %acqF = app.edit_acqF.Value;
            acqF = app.parentApp.acqF;
            app.us_startI = us_start.*acqF;
            app.us_endI = us_end.*acqF;
            app.ds_startI = ds_start.*acqF;
            app.ds_endI = ds_end*acqF;   
        end
        
        function Extract_SUpos_SWA(app)
            % find the number of positive single units located within up states and down states
            % first set the needed values
            channel = app.chNumber.Value;
            correctmaxAt = app.maxAt{channel, app.trNumber.Value};
            nus = app.nus;
            len = length(correctmaxAt);
            su_in_us = 0; % this is going to count the number of single units in upstates
            % now we will first loop through every up state
            for us = 1:nus
                idx1 = app.us_startI(us); % and for each up state find the start..
                idx2 = app.us_endI(us); % and end point
                % then we can loop through the indexes of su
                for sui = 1:len
                    if correctmaxAt(sui) > idx1 && correctmaxAt(sui) < idx2
                        su_in_us = su_in_us + 1;
                    end
                    % add an if statement to stop when you have reached higher than the
                    % current up state, just to save time
                    if correctmaxAt(sui) > idx2
                        break
                    end
                end
            end
            
            % Now let's do the same for the down states
            nds = app.nds;
            su_in_ds = 0;
            for ds = 1:nds
                idx1 = app.ds_startI(ds);
                idx2 = app.ds_endI(ds);
                for sui = 1:len
                    if correctmaxAt(sui) > idx1 && correctmaxAt(sui) < idx2
                        su_in_ds = su_in_ds + 1;
                    end
                    if correctmaxAt(sui) > idx2
                        break
                    end
                end
            end
            
            % test if correct
            %if su_in_us + su_in_ds > app.nMax
            %    error ('single units in upstates + down is larger than total single units: something wrong in the code')
            %end
            
            % add to the handles structure and update handles structure
            app.pos_su_in_us = su_in_us;
            app.pos_su_in_ds = su_in_ds;            
        end
        
        
        function Extract_SUneg_SWA(app)
            % find the number of negative single units located within up states and down states
            % works the same as the function for the positive single units
            nus = app.nus;
            channel = app.chNumber.Value;
            correctminAt = app.minAt{channel, app.trNumber.Value};
            len = length(correctminAt);
            su_in_us = 0;
            for us = 1:nus
                idx1 = app.us_startI(us);
                idx2 = app.us_endI(us);
                for sui = 1:len
                    if correctminAt(sui) > idx1 && correctminAt(sui) < idx2
                        su_in_us = su_in_us + 1;
                    end
                    if correctminAt(sui) > idx2
                        break
                    end
                end
            end
            
            nds = app.nds;
            su_in_ds = 0;
            for ds = 1:nds
                idx1 = app.ds_startI(ds);
                idx2 = app.ds_endI(ds);
                for sui = 1:len
                    if correctminAt(sui) > idx1 && correctminAt(sui) < idx2
                        su_in_ds = su_in_ds + 1;
                    end
                    if correctminAt(sui) > idx2
                        break
                    end
                end
            end
            
            %if su_in_us + su_in_ds > app.nMin
            %    error ('single units in upstates + down is larger than total single units: something wrong in the code')
            %end
            
            app.neg_su_in_us = su_in_us;
            app.neg_su_in_ds = su_in_ds;
        end
        
        
        function plotLFP(app)
            % function to plot the LFP

            
            % Obtain  the LFP vector:
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            LFPreal = squeeze(app.parentApp.LFP(ch,tr,:));
            
            % Find the Y axes:
            %min and max values of the LFP
            LFPreal_min = min(LFPreal); LFPreal_max = max(LFPreal);
            if LFPreal_min < 0
                LFP_min_temp = ceil(10*abs(LFPreal_min));
                y_min = -LFP_min_temp/10;
            else 
                LFP_min_temp = ceil(10*LFPreal_min);
                y_min = LFP_min_temp/10;
            end
            if LFPreal_max < 0
                LFP_max_temp = ceil(10*abs(LFPreal_max));
                y_max = -LFP_max_temp/10;
            else 
                LFP_max_temp = ceil(10*LFPreal_max);
                y_max = LFP_max_temp/10;
            end


            % Now plot the LFP:
            plot(app.LFPaxes,app.time(app.x_min_index:app.x_max_index), LFPreal(app.x_min_index:app.x_max_index), 'k');
            xlim(app.LFPaxes,[app.x_min_time app.x_max_time]);
            ylim(app.LFPaxes,[y_min y_max]);
        end
        
              
        function plotSUmax(app)
            % function to plot the positive 'peaks' (single units)
            % use the same x limits as created for the LFP
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            xlim(app.SUaxes,[app.x_min_time app.x_max_time]);
            y_su_max = ismember(app.x, app.maxAt{ch,tr}(:)); % creates a vector of the same length of x, with values 0 or 1 if the same value
            % is a member of maxAt, in other words if there is a spike.
            plot(app.SUaxes,app.time(app.x_min_index:app.x_max_index), y_su_max(app.x_min_index:app.x_max_index), 'r');
        end
        
        function plotSUmin(app)
            % function to plot the negative 'peaks' (single units)
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            xlim(app.SUaxes,[app.x_min_time app.x_max_time]);
            y_su_min = ismember(app.x, app.minAt{ch,tr}(:));
            plot(app.SUaxes,app.time(app.x_min_index:app.x_max_index), -1*y_su_min(app.x_min_index:app.x_max_index), 'b');
        end


        function plotMasterFunction(app)
            % this is the "master" plot function. It calls specific plot functions accorting to the respective checkbox state
            
            %clear all the axes for first
            cla(app.LFPaxes);
            cla(app.SUaxes);
            cla(app.HPaxes);
            % note that LFPaxes and SUaxes 'NextPlot' property is set to 'add' by default (they always "hold on")
            
            % Find the X axes:
            % convert x axis to time
            acqF = app.parentApp.acqF;
            app.time = (app.x-1)./acqF;
            if isempty(get(app.edit_tStart_plot, 'Value')) % if the user did not enter the start time, i.e. the edit text box is empty
                app.x_min_time = app.time(1); % the start time in seconds
                app.x_min_index = app.x(1); % index of the start time
            else 
                app.x_min_time = str2double(get(app.edit_tStart_plot, 'Value')); % if the user entered the start time, find the entered number
                if app.x_min_time < app.time(1) % quality control
                    app.x_min_time = app.time(1);
                end
                app.x_min_index = app.x_min_time*acqF+1;
            end
            % the same for the end time
            if isempty(get(app.edit_tEnd_plot, 'Value'))
                app.x_max_time = app.time(end);
                app.x_max_index = app.x(end);
            else
                app.x_max_time = str2double(get(app.edit_tEnd_plot, 'Value'));
                if app.x_max_time > app.time(end) % quality control
                    app.x_max_time = app.time(end);
                end
                app.x_max_index = app.x_max_time*acqF+1;
            end
            
            % if end time is smaller than start time
            if app.x_max_time <= app.x_min_time
                error('end time has to be larger than start time')
            end


            %call the specific plot functions
            if app.checkbox_plotLFP.Value
                plotLFP(app)
            end
            if app.checkbox_SUmax.Value
                plotSUmax(app)
            end    
            if app.checkbox_SUmin.Value
                plotSUmin(app)
            end
            if app.checkbox_hp.Value
                plotHP(app)
            end
        end
        
        function plotHP(app)
            %t = (0:app.parentApp.dtaLen-1)*app.parentApp.sp; %time vector
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;
            plot(app.HPaxes,app.time(app.x_min_index:app.x_max_index),squeeze(app.LFP_hp(ch,tr,app.x_min_index:app.x_max_index)),'k')
            plot(app.HPaxes,[app.time(app.x_min_index),app.time(app.x_max_index)],[app.thr(ch),app.thr(ch)],'Color','r')
            plot(app.HPaxes,[app.time(app.x_min_index),app.time(app.x_max_index)],[-app.thr(ch),-app.thr(ch)],'Color','b')
            app.HPaxes.XLim = [app.time(app.x_min_index),app.time(app.x_max_index)];
        end
    
    %% February 2019 Didi: all stuff below was added by Gabriele. I commented it out to make sure no changed have been made to program as I intended to use it
        
        %function w = fixedKernelBandwidth(app,fireRate_raw)
            %t = ((fireRate_raw > 0)-1)*app.parentApp.sp; %spike times
            %t = find(fireRate_raw(app.chNumber.Value,:) > 0); %spike indices
            
            %minimization function as in shimazaki 2009
            
            %toBeMin = @(omega) shimazakiFixed(app,omega,t);
%             options = optimset('Display','Iter');
%             w = fminsearch(toBeMin,60,options);
            %w = fminsearch(toBeMin,60);
        %end
        
        %function res = shimazakiFixed(app,w,t)
        %    N = length(t);
        %    s=0;
        %    for j=2:N
        %        for i=1:j-1
        %            s = s+exp(-(t(i)-t(j))^2/(2*w)^2)-2*sqrt(2)*exp(-(t(i)-t(j))^2/(2*w^2));
        %        end
        %    end
        %    res = N/w+2/w*s; %to be continued
        %end
        
        %function [out] = fromSUpeaks_to_LFPpeaks(app,sourceI,sourceV)
            % sourceI can be app.maxAt o  app.minAt;
            % sourceV can be app.LFP_hp or app.parentApp.LFP
            %posit = sourceI; % position of SU (expressed in indices)

            %y_tmp = num2cell(sourceV,3); %the cell y{i,j} stores a vector all the points from sourceV(i,j,:), so from the j-th trial of the i-th channel
            %y = cellfun(@squeeze,y_tmp,'UniformOutput',0);
            
            %fun = @(ind,x) x(ind);
            %out_tmp = cellfun(fun,posit,y,'UniformOutput',0);

            %out = cell(app.parentApp.nCh,1);
            %for iCh = 1:app.parentApp.nCh
            %    out{iCh,1} = vertcat(out_tmp{iCh,:});
            %end
        %end
        
        %% End of Gabriele's part
        
        function showSuOutput(app)
            % show the SU numbers on the appropriate GUI objects
            
            ch = app.chNumber.Value;
            tr = app.trNumber.Value;

            % Show the total number of upward going and downward going peaks
            set(app.label_nMax, 'Text', num2str(app.nMax(ch,tr)));
            set(app.label_nMin, 'Text', num2str(app.nMin(ch,tr)));
            set(app.label_nTot, 'Text', num2str(sum(app.nMin(ch,:))+sum(app.nMax(ch,:))));
            % Also call the plot function
            plotMasterFunction(app)      
        end
    end
    
    methods (Access = public)
        
         
        function setSpinners(app)
            % update BP spinner
            if get(app.parentApp.NumberofBPsEditField,'Value') == 1
                app.BpSelection.Limits = [1,inf];
                app.BpSelection.Enable = 'off';
            else
                app.BpSelection.Limits(2) = app.parentApp.NumberofBPsEditField.Value;
                app.BpSelection.Enable = 'on';
            end
            % update channel spinner
            if ~isempty(app.parentApp.nCh)
                if app.parentApp.nCh == 1
                    app.chNumber.Limits = [1,inf];
                    app.chNumber.Enable = 'off';
                else
                    app.chNumber.Limits = [1,app.parentApp.nCh];
                    app.chNumber.Enable = 'on';
                end
            end
            % update trial spinner
            if ~isempty(app.parentApp.nTrials)
                if app.parentApp.nTrials == 1
                    app.trNumber.Limits = [1,inf];
                    app.trNumber.Enable = 'off';
                else
                    app.trNumber.Limits = [1,app.parentApp.nTrials];
                    app.trNumber.Enable = 'on';
                end
            end
        end
        
        function refinepeaks(app)
%             app.maxAt = cell(app.parentApp.nCh,app.parentApp.nTrials);
%             app.minAt = cell(app.parentApp.nCh,app.parentApp.nTrials);
            halfwin = round(app.RefractoryperiodmsEditField.Value/1000/2/app.parentApp.sp);
            
            for iCh = 1:app.parentApp.nCh
                for iTr = 1:app.parentApp.nTrials
                    % remove non-peak positive values
                    for iMax = app.nMax(iCh,iTr):-1:1
                        curr_i = app.maxAt{iCh,iTr}(iMax);
                        from = max(1,curr_i-halfwin);
                        to = min(app.parentApp.dtaLen,curr_i+halfwin);
                        [~,localmax_i] = max(abs(app.LFP_hp(iCh,iTr,from:to)));
                        % debug
%                         figure
%                         plot(squeeze(app.LFP_hp(iCh,iTr,from:to)))
%                         hold on
%                         tmp = squeeze(app.LFP_hp(iCh,iTr,from:to));
%                         plot(localmax_i,tmp(localmax_i),'o')
%                         plot(curr_i-from+1,tmp(curr_i-from+1),'+')
%                         legend('hp','max','center')
                        
                        if localmax_i ~= curr_i-from+1
                            app.maxAt{iCh,iTr}(iMax)=[];
                            app.nMax(iCh,iTr)=app.nMax(iCh,iTr)-1;
                        end
                    end
                    
                    % remove non-peak negative values
                    for iMin = app.nMin(iCh,iTr):-1:1
                        curr_i = app.minAt{iCh,iTr}(iMin);
                        from = max(1,curr_i-halfwin);
                        to = min(app.parentApp.dtaLen,curr_i+halfwin);
                        [~,localmax_i] = max(abs(app.LFP_hp(iCh,iTr,from:to)));
                        if localmax_i ~= curr_i-from+1
                            app.minAt{iCh,iTr}(iMin)=[];
                            app.nMin(iCh,iTr)=app.nMin(iCh,iTr)-1;
                        end
                    end
                end
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function singleUnits_OpeningFcn(app, parentApp)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to singleunits2 (see VARARGIN)
%             
%             % Choose default command line output for singleunits2
%             app.output = hObject;
%             recover the handle to ZebraExplore
            app.parentApp = parentApp;

%            
            
%             %initialize channels and trials spinners
            setSpinners(app)
        end

        % Value changed function: checkbox_calculateSUUPDOWN
        function checkbox_calculateSUUPDOWN_Callback(app, event)
            
            if app.checkbox_calculateSUUPDOWN.Value  
                Extract_SWA (app);
                Extract_SUpos_SWA (app);
                Extract_SUneg_SWA (app);
                % creation of a list containing all output data
                % first column: entire trace
                % second column: in up states
                % third column: in down states
                
                ch = app.chNumber.Value;
                tr = app.trNumber.Value;
                list = {1 1 1};
                % first row: number of positive peaks
                list(1 ,1) = num2cell(app.nMax(ch,tr));
                list(1, 2) = num2cell(app.pos_su_in_us);
                list(1, 3) = num2cell(app.pos_su_in_ds);
                % second row: fraction of postive peaks
                list(2, 1) = num2cell((app.nMax(ch,tr)/app.nMax(ch,tr))*100);
                list(2, 2) = num2cell((app.pos_su_in_us/app.nMax(ch,tr))*100);
                list(2, 3) = num2cell((app.pos_su_in_ds/app.nMax(ch,tr))*100);
                % third row: number of negative peaks
                list(3, 1) = num2cell(app.nMin(ch,tr));
                list(3, 2) = num2cell(app.neg_su_in_us);
                list(3, 3) = num2cell(app.neg_su_in_ds);
                % fourth row: fraction of negative peaks
                list(4, 1) = num2cell((app.nMin(ch,tr)/app.nMin(ch,tr))*100);
                list(4, 2) = num2cell((app.neg_su_in_us/app.nMin(ch,tr))*100);
                list(4, 3) = num2cell((app.neg_su_in_ds/app.nMin(ch,tr))*100);
                % fifth row: number of total peaks
                total = app.nMin(ch,tr)+app.nMax(ch,tr);
                total_in_us = app.neg_su_in_us+app.pos_su_in_us;
                total_in_ds = app.neg_su_in_ds+app.pos_su_in_ds;
                list(5 ,1) = num2cell(total);
                list(5, 2) = num2cell(total_in_us);
                list(5, 3) = num2cell(total_in_ds);
                % sixth row: fraction of total peaks
                list(6, 1) = num2cell((total/total)*100);
                list(6, 2) = num2cell((total_in_us/total)*100);
                list(6, 3) = num2cell((total_in_ds/total)*100);
            
                % finally set this list as output data in the table
                set(app.uitable_output,'Data',list);
                % save the data table
                app.datatable = list;
            else 
                emptylist = {};
                set(app.uitable_output, 'Data', emptylist);
            end
        end

        % Button pushed function: calculate_SU
        function calculate_SU_Callback(app, event)
%             this function will execute the findpeaks function and display counted positive
%             and negative peaks
            
            disp('Finding all peaks...')
            findpeaks(app);
            
            disp('Refining peaks...')
            refinepeaks(app);
            
            disp('Peaks found')
            showSuOutput(app); % show the output on the GUI     
        end

        % Value changed function: checkbox_SUmin
        function checkbox_SUmin_Callback(app, event)
            value = app.checkbox_SUmin.Value;
            
        end

        % Value changed function: checkbox_hp, checkbox_plotLFP
        function checkbox_plotLFP_Callback(app, event)
            plotMasterFunction(app)
        end

        % Value changed function: checkbox_SUmax
        function checkbox_SUmax_Callback(app, event)
                      
        end

        % Value changed function: checkbox_savetimes
        function checkbox_savetimes_Callback(app, event)
            % hObject    handle to checkbox_savetimes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if app.checkbox_savetimes.Value
                acqF = app.parentApp.acqF;
                nus = app.nus;
                nds = app.nds;
                %create the several columns first: first timing positive single units
                %states, then timing negative single units, timing up states start and end and timing
                %down states start and end
                correctmaxAt = app.maxAt{app.chNumber.Value, app.trNumber.Value};
                correctminAt = app.minAt{app.chNumber.Value, app.trNumber.Value};
                A = (correctmaxAt-1)./acqF;
                B = (correctminAt-1)./acqF;
                C = app.parentApp.evDet.upstates.fromT(1:nus);
                D = app.parentApp.evDet.upstates.toT(1:nus);
                E = app.parentApp.evDet.downstates.fromT(1:nds);
                F = app.parentApp.evDet.downstates.toT(1:nds);
            
                % then create the to be printed struct
                output = struct('TimeposSU', A, 'TimenegSU', B, 'UPstart', C, 'UPend', D, 'DOWNstart', E, 'DOWNend', F);
            
            
                %then create the text file. First find the correct name. I added the
                %possibility to add ltp number so that when several sums are recorded
                %at the ephys setup, which appear in the same folder, each of these
                %sums the data is separately stored
                name = app.edit_ltpnumber.Value;
                channel = app.chNumber.Value;
                fileout = [app.parentApp.dir_in 'SUanalysis' name 'Ch' num2str(channel) '.dat'];
                lengthstruct = length(fieldnames(output));
                fields = fieldnames(output);
                % find the field with the longest length
                ls = [app.nMax(channel, app.trNumber.Value) app.nMin(channel, app.trNumber.Value) app.nus app.nds];
                maximum = max(ls);
            
                % open and start printing
                fid = fopen(fileout, 'w');
                fprintf(fid,'%s\t', fields {:});
                fprintf(fid, '\n');
                for i =  1:maximum
                    for ff = 1:lengthstruct
                        if i > length(output.(fields{ff}))
                            fprintf(fid, '\t');
                        else
                            fprintf(fid,'%g\t', output.(fields{ff})(i));
                        end
                    end
                    fprintf(fid,'\n');
                end
                %fid = fclose(fid);
                fclose(fid);
            end
        end

        % Button pushed function: pushbutton_reset
        function pushbutton_reset_Callback(app, event)
            % hObject    handle to pushbutton_reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %function to reset the time to the entire range of the LFP recording
            %if get(hObject, 'Value')
                % set the edit boxes of the start time and end time to empty again
                set(app.edit_tStart_plot, 'Value', '');
                set(app.edit_tEnd_plot, 'Value', '');
                % then replot everything; the plot master function will automatically
                % determine the entire range
                plotMasterFunction(app);
            %end
        end

        % Button pushed function: pushbutton_scroll_left
        function pushbutton_scroll_left_Callback(app, event)
            % hObject    handle to pushbutton_scroll_left (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %function to scroll though the plots of LFP and single units
            %if get(hObject, 'Value')
                currentx_min = str2double(get(app.edit_tStart_plot, 'Value'));
                currentx_max = str2double(get(app.edit_tEnd_plot, 'Value'));
                % find the current distance between the start and end time points
                diff = currentx_max-currentx_min;
                % use it to calculate the new timepoints
                newx_min = currentx_min-diff;
                newx_max = currentx_max-diff;
%                 if newx_min >= 0 && newx_max <= app.time(end) %test that the new time points fall within the time range of the entire recording
                if newx_min < app.time(1) %quality control
                    newx_min = app.time(1);
                    newx_max = app.time(1)+diff;
                end
                % if yes, you can update the tstart and tend edit boxes
                set(app.edit_tStart_plot, 'Value', num2str(newx_min));
                set(app.edit_tEnd_plot, 'Value', num2str(newx_max));
                %and then re-execute the plotting
                
                %% Below is all commented out, since it is no longer necessary with the plot master function
                %plotLFP(app);
                
                % find out which single unit plotting function to use
                %if get(app.checkbox_plotLFP, 'Value')
                %    plotSUall(app);
                %elseif get(app.checkbox_SUmin, 'Value')
                %    plotSUmin(app);
                %elseif get(app.checkbox_SUmax, 'Value')
                %    plotSUmax(app);
                %end
%                 else
%                     axes(app.LFPaxes); cla; % if the time range is outside of the recording range, empty the plots
%                     axes(app.SUaxes); cla;
%                 end
%                 % reset the pushbutton value to 0, so that when you click again it
%                 % changes to 1 and executes the code to scroll again, so you don't have
%                 % to unclick and click again
%                 set(hObject,'value',0);
            %end
            plotMasterFunction(app)
        end

        % Button pushed function: pushbutton_scroll_right
        function pushbutton_scroll_right_Callback(app, event)
            % hObject    handle to pushbutton_scroll_right (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % function to scroll right through the plots, the same as the left
            % scrolling function above
%             if get(hObject, 'Value')
            currentx_min = str2double(get(app.edit_tStart_plot, 'Value'));
            currentx_max = str2double(get(app.edit_tEnd_plot, 'Value'));
            diff = currentx_max-currentx_min;
            newx_min = currentx_min+diff;
            newx_max = currentx_max+diff;
            if newx_max > app.time(end)
                newx_max = app.time(end);
                newx_min = app.time(end)+diff;
            end
            set(app.edit_tStart_plot, 'Value', num2str(newx_min));
            set(app.edit_tEnd_plot, 'Value', num2str(newx_max));
            
            %% Below is all commented out, since it is no longer necessary with the plot master function
            %plotLFP(app);

            %if get(app.checkbox_plotLFP, 'Value')
            %    plotSUall(app);
            %elseif get(app.checkbox_SUmin, 'Value')
            %    plotSUmin(app);
            %elseif get(app.checkbox_SUmax, 'Value')
            %    plotSUmax(app);
            %end
%             else axes(app.LFPaxes); cla;
%                 axes(app.SUaxes); cla;
%             end
%                 set(hObject,'value',0);
%             end
            plotMasterFunction(app)
        end

        % Button pushed function: reset_SU
        function reset_SU_Callback(app, event)
            set(app.label_nMax, 'Value', '-');
            set(app.label_nMin, 'Value', '-');
        end

        % Button pushed function: ReplotButton
        function replot_Callback(app, event)
            %just call the plotMasterFunction
            plotMasterFunction(app)
        end

        % Button pushed function: rasterPlot_bt
        function rasterPlot_Callback(app, event)
           %% This function is build by Gabriele, comment it out to prevent it from interrupting with the intended purpose of the program
            
            % Build the raster plot matrix
            %nCh = app.parentApp.nCh;
            %nTr = app.parentApp.nTrials;
            %len = app.parentApp.dtaLen;
            %rasterMat = zeros(nCh,nTr,len); %inizialize the matrix
            %for iCh = 1:nCh %loop channels and trials
            %    for iTr = 1:nTr
            %        nPosSU = length(app.maxAt{iCh,iTr}(:));
            %        for jPos=1:nPosSU
            %            rasterMat(iCh,iTr,app.maxAt{iCh,iTr}(jPos)) = 1; % set as 1 the timepoint of positive SUs
            %        end
                    
            %        nNegSU = length(app.minAt{iCh,iTr}(:));
            %        for jMin=1:nNegSU
            %            rasterMat(iCh,iTr,app.minAt{iCh,iTr}(jMin)) = 1; % set as 1 the timepoint of positive SUs
            %        end
                    %rasterMat(iCh,iTr,logical(app.maxAt{iCh,iTr}(:)) | logical(app.minAt{iCh,iTr}(:))) = 1;
            %    end
            %end
            %spikeBinary = logical(rasterMat); % matrix for the rasterPlot
            
            %fireRate_raw = squeeze(sum(rasterMat,2)); % 2D matrix, channels x time
            %figure
            %ax1 = subplot(2,1,1);
            %plot(ax1,fireRate_raw(1,:))
%             spikeTimes = cell(nTr,1);
%             for i = 1:nTr
%                 spikeTimes = find(spikeBinary > 0);
%             end
            % spikeTimes = mat2cell(find(spikeBinary > 0),ones(1:nCh),ones(1:nTr));
            %spikeTimes = num2cell(find(spikeBinary > 0),3);
            
            %plotSpikeRaster(squeeze(spikeBinary(app.chNumber.Value,:,:)),'PlotType','vertline','TimePerBin',0.002);
            %ax1.YTickLabel = num2cell(1:nTr);
            %uncorrectTimes = str2double(ax1.XTickLabel);
            %ax1.XTickLabel = num2str((uncorrectTimes)*app.parentApp.sp); %always starts from 0
            %ax1.Title.String = 'raw fire rate';
            %ax1.XLabel.String = 'Time (s)';
            
            %app.x = indices
            %gaussian kernel function with parameter w.
            %w = app.kernelBW_edit.Value; % totally random, just try
            %w = fixedKernelBandwidth(app,fireRate_raw); %vediamo cosa viene
            %disp(w)
            %s = -len/2:len/2; %kernel domain
            %ker = (sqrt(2*pi)*w)^(-1) * exp(-1*(s).^2./(2*w^2));
            
            %fireRate_smooth = conv(fireRate_raw(1,:),ker);
            %app.fireRate_smooth = conv(ker,fireRate_raw(app.chNumber.Value,:),'same');
            %ax2 = subplot(2,1,2);
            %t4plot = (0:length(app.fireRate_smooth)-1)*app.parentApp.sp;
            %plot(ax2,t4plot,app.fireRate_smooth)
            %ax2.Title.String = ['smooth fire rate (kernel BW = ' num2str(w) ')'];
            %ax2.XLabel.String = 'Time (s)';
            
            
        end

        % Button pushed function: HPhisto_bt
        function HPhisto_bt_Callback(app, event)
            %% This function is build by Gabriele, comment it out to prevent it from interrupting with the intended purpose of the program
            %ch = app.chNumber.Value;
            
            %hp = app.LFP_hp(ch,:,:);
            %hp = hp(:);
            %figure
            %ax = axes;
            %histogram(hp,linspace(min(hp),max(hp),21))
            %ax.YScale = 'log';
            %hold on
            %plot([-app.thr(1),-app.thr(1)],ylim,'r')
            %plot([app.thr(1),app.thr(1)],ylim,'r')
            %ax.XLabel.String = 'Voltage';
            %ax.YLabel.String = 'Conunts';
            %su = hp>app.thr(ch) | hp<-app.thr(ch);
            %counts = sum(su);
            %su_freq = counts/length(hp);
            %ax.Title.String = ['SU number: ' num2str(counts) ',  SU frequency: ' num2str(su_freq)];
            
            %%% also check if SU amplitudes correlate with LFP amplitude %%----------------------------------------------
            % use the function fromSUpeaks_to_LFPpeaks(app,sourceI,sourceV), where:
            %   -sourceI is the source of SU indices; it can be app.minAt or app.maxAt
            %   -sourceV is the source of LFP values; it can be app.LFP_hp or app.parentApp.LFP.
            
            %posHp = fromSUpeaks_to_LFPpeaks(app,app.maxAt,app.LFP_hp); %high-passed LFP values relative to positive-SU timepoints
            %negHp = fromSUpeaks_to_LFPpeaks(app,app.minAt,app.LFP_hp); %high-passed LFP values relative to negative-SU timepoints
            
            %posLfp = fromSUpeaks_to_LFPpeaks(app,app.maxAt,app.parentApp.LFP); %high-passed LFP values relative to positive-SU timepoints
            %negLfp = fromSUpeaks_to_LFPpeaks(app,app.minAt,app.parentApp.LFP); %high-passed LFP values relative to negative-SU timepoints
            
            %totHp = cellfun(@vertcat,posHp,negHp,'UniformOutput',0);
            %totLfp = cellfun(@vertcat,posLfp,negLfp,'UniformOutput',0);
            
            %figure
            %axCorr = axes;
%             plot(axCorr,totLfp{app.chNumber.Value},totHp{app.chNumber.Value},'o')
%             axCorr.XLabel.String = 'LFP amplitude (mV)';
%             axCorr.YLabel.String = 'high-passed LFP amplitude (mV)';
            %plot(axCorr,abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value}),'o')
            %axCorr.XLabel.String = 'absolute LFP amplitude (mV)';
            %axCorr.YLabel.String = 'absolute high-passed LFP amplitude (mV)';
            %axCorr.Title.String = 'Correlation between LFP and hp-filtered LFP at SU peaks';
            
            %[p,S] = polyfit(abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value}),1);
            %hold(axCorr,'on')
            %plot(axCorr,abs(totLfp{app.chNumber.Value}),p(2)+abs(totLfp{app.chNumber.Value}).*p(1));
%             [rho,pval] = corr([abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value})],Y);
%             hold(axCorr, 'on')
            %r = corrcoef(abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value}));
            %txt = text(axCorr,0,0,0,['r_P_e_a_r_s_o_n = ' num2str(r(1,2))]);
            %txt.Position = [axCorr.XLim(2)-txt.Extent(3), axCorr.YLim(2)-txt.Extent(4), 0]; %position txt to the upper-right corner
            
            %also for all the points, not only for SU
            %figure
            %axCorr = axes;
%             plot(axCorr,totLfp{app.chNumber.Value},totHp{app.chNumber.Value},'o')
%             axCorr.XLabel.String = 'LFP amplitude (mV)';
%             axCorr.YLabel.String = 'high-passed LFP amplitude (mV)';
            %X = abs(app.parentApp.LFP(app.chNumber.Value,:,:));
            %X = X(:);
            %Y = abs(app.parentApp.bandPassed_LFP(5,app.chNumber.Value,:,:));
            %Y = Y(:);
            %plot(axCorr,X,Y,'o')
            %axCorr.XLabel.String = 'absolute LFP amplitude (mV)';
            %axCorr.YLabel.String = 'absolute high-passed LFP amplitude (mV)';
            %axCorr.Title.String = 'Correlation between LFP and hp-filtered LFP (all points)';
            
            %[p,S] = polyfit(X,Y,1);
            %hold(axCorr,'on')
            %plot(axCorr,X,p(2)+Y.*p(1));
%             [rho,pval] = corr([abs(totLfp{app.chNumber.Value}),abs(totHp{app.chNumber.Value})],Y);
%             hold(axCorr, 'on')
            %r = corrcoef(X,Y);
            %txt = text(axCorr,0,0,0,['r_P_e_a_r_s_o_n = ' num2str(r(1,2))]);
            %txt.Position = [axCorr.XLim(2)-txt.Extent(3), axCorr.YLim(2)-txt.Extent(4), 0]; %position txt to the upper-right corner
            
        end

        % Value changed function: edit_tEnd_plot
        function edit_tEnd_plot_Callback(app, event)
            plotMasterFunction(app)
        end

        % Value changed function: edit_tStart_plot
        function edit_tStart_plot_Callback(app, event)
            plotMasterFunction(app)            
        end

        % Value changed function: chNumber
        function chNumber_Callback(app, event)
            plotMasterFunction(app)
            showSuOutput(app)
        end

        % Value changed function: trNumber
        function trNumber_Callback(app, event)
            plotMasterFunction(app)
            showSuOutput(app)
        end

        % Button pushed function: CopyTableButton
        function CopyTableButtonPushed(app, event)
            % This function copies the table to the clipboard 
            tablesize = size(app.datatable);
            str = '';
            for i = 1:tablesize(1)
                for j = 1:tablesize(2)
                    if j == tablesize(2)
                        str = sprintf('%s%f',str,app.datatable{i,j});
                    else
                        str = sprintf('%s%f\t',str,app.datatable{i,j});
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard ('copy',str);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'singleunits2';
            app.uibuttongroup3 = uibuttongroup(app.figure1);
            app.uibuttongroup3.Title = 'Plotting';
            app.LFPaxes = uiaxes(app.uibuttongroup3);
            ylabel(app.LFPaxes, 'LFP (mV)')
            app.SUaxes = uiaxes(app.uibuttongroup3);
            xlabel(app.SUaxes, 'Time (s)')
            ylabel(app.SUaxes, 'SU')
            app.SUaxes.YLim = [-1 1];
            app.checkbox_plotLFP = uicheckbox(app.uibuttongroup3);
            app.checkbox_plotLFP.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_plotLFP.Text = 'Plot LFP';
            app.checkbox_plotLFP.Value = true;
            app.checkbox_SUmax = uicheckbox(app.uibuttongroup3);
            app.checkbox_SUmax.ValueChangedFcn = createCallbackFcn(app, @checkbox_SUmax_Callback, true);
            app.checkbox_SUmax.Text = 'Plot positive SU';
            app.checkbox_SUmax.Value = true;
            app.checkbox_SUmin = uicheckbox(app.uibuttongroup3);
            app.checkbox_SUmin.ValueChangedFcn = createCallbackFcn(app, @checkbox_SUmin_Callback, true);
            app.checkbox_SUmin.Text = 'Plot negative SU';
            app.checkbox_SUmin.Value = true;
            app.pushbutton_scroll_left = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_scroll_left.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_scroll_left_Callback, true);
            app.pushbutton_scroll_left.Text = '< scroll';
            app.pushbutton_reset = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_reset.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_reset_Callback, true);
            app.pushbutton_reset.Text = 'Reset';
            app.pushbutton_scroll_right = uibutton(app.uibuttongroup3, 'push');
            app.pushbutton_scroll_right.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_scroll_right_Callback, true);
            app.pushbutton_scroll_right.Text = 'scroll >';
            app.edit_tStart_plot = uieditfield(app.uibuttongroup3, 'text');
            app.edit_tStart_plot.ValueChangedFcn = createCallbackFcn(app, @edit_tStart_plot_Callback, true);
            app.edit_tEnd_plot = uieditfield(app.uibuttongroup3, 'text');
            app.edit_tEnd_plot.ValueChangedFcn = createCallbackFcn(app, @edit_tEnd_plot_Callback, true);
            app.ReplotButton = uibutton(app.uibuttongroup3, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @replot_Callback, true);
            app.ReplotButton.Text = 'Replot';
            app.HPaxes = uiaxes(app.uibuttongroup3);
            ylabel(app.HPaxes, 'hp LFP (mV)')
            app.HPaxes.XTickLabel = '';
            app.checkbox_hp = uicheckbox(app.uibuttongroup3);
            app.checkbox_hp.ValueChangedFcn = createCallbackFcn(app, @checkbox_plotLFP_Callback, true);
            app.checkbox_hp.Text = 'Plot high-passed LFP';
            app.checkbox_hp.Value = true;
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Count Number SU';
            app.text2 = uilabel(app.uibuttongroup1);
            app.text2.Text = 'Use high pass filtered data at 300 Hz';
            app.text4 = uilabel(app.uibuttongroup1);
            app.text4.Text = 'Number of + peaks:';
            app.text5 = uilabel(app.uibuttongroup1);
            app.text5.Text = 'Number of - peaks:';
            app.label_nMax = uilabel(app.uibuttongroup1);
            app.label_nMax.Text = '-';
            app.label_nMin = uilabel(app.uibuttongroup1);
            app.label_nMin.Text = '-';
            app.calculate_SU = uibutton(app.uibuttongroup1, 'push');
            app.calculate_SU.ButtonPushedFcn = createCallbackFcn(app, @calculate_SU_Callback, true);
            app.calculate_SU.Text = 'Calculate';
            app.reset_SU = uibutton(app.uibuttongroup1, 'push');
            app.reset_SU.ButtonPushedFcn = createCallbackFcn(app, @reset_SU_Callback, true);
            app.reset_SU.Text = 'Reset';
            app.text5_2 = uilabel(app.uibuttongroup1);
            app.text5_2.Text = 'Peaks in the whole selected channel:';
            app.label_nTot = uilabel(app.uibuttongroup1);
            app.label_nTot.Text = '-';
            app.HPhisto_bt = uibutton(app.uibuttongroup1, 'push');
            app.HPhisto_bt.ButtonPushedFcn = createCallbackFcn(app, @HPhisto_bt_Callback, true);
            app.HPhisto_bt.Text = 'HP histogram';
            app.trNumber = uispinner(app.uibuttongroup1);
            app.trNumber.Limits = [1 Inf];
            app.trNumber.ValueChangedFcn = createCallbackFcn(app, @trNumber_Callback, true);
            app.trNumber.Value = 1;
            app.trNumberTxt = uilabel(app.uibuttongroup1);
            app.trNumberTxt.Text = 'Trial ';
            app.chNumber = uispinner(app.uibuttongroup1);
            app.chNumber.Limits = [1 Inf];
            app.chNumber.ValueChangedFcn = createCallbackFcn(app, @chNumber_Callback, true);
            app.chNumber.Value = 1;
            app.chNumberTxt = uilabel(app.uibuttongroup1);
            app.chNumberTxt.Text = 'Channel';
            app.BandPassLabel = uilabel(app.uibuttongroup1);
            app.BandPassLabel.Text = 'BandPass';
            app.BpSelection = uispinner(app.uibuttongroup1);
            app.BpSelection.Limits = [1 Inf];
            app.BpSelection.Value = 1;
            app.ThresholdmultiplierEditFieldLabel = uilabel(app.uibuttongroup1);
            app.ThresholdmultiplierEditFieldLabel.Text = 'Threshold multiplier';
            app.ThresholdmultiplierEditField = uieditfield(app.uibuttongroup1, 'numeric');
            app.ThresholdmultiplierEditField.Value = 4;
            app.RefractoryperiodmsEditFieldLabel = uilabel(app.uibuttongroup1);
            app.RefractoryperiodmsEditFieldLabel.Text = 'Refractory period (ms)';
            app.RefractoryperiodmsEditField = uieditfield(app.uibuttongroup1, 'numeric');
            app.RefractoryperiodmsEditField.Value = 5;
            app.uibuttongroup4 = uibuttongroup(app.figure1);
            app.uibuttongroup4.Title = 'SU in UP and DOWN states';
            app.checkbox_calculateSUUPDOWN = uicheckbox(app.uibuttongroup4);
            app.checkbox_calculateSUUPDOWN.ValueChangedFcn = createCallbackFcn(app, @checkbox_calculateSUUPDOWN_Callback, true);
            app.checkbox_calculateSUUPDOWN.Text = 'Calculate';
            app.uitable_output = uitable(app.uibuttongroup4);
            app.uitable_output.ColumnName = {'Entire Trace'; 'In UP states'; 'In DOWN states'};
            app.uitable_output.RowName = {'# + peaks'; '% + peaks'; '# - peaks'; '% - peaks'; '# all peaks'; '% all peaks'};
            app.uitable_output.ColumnEditable = [false false false];
            app.checkbox_savetimes = uicheckbox(app.uibuttongroup4);
            app.checkbox_savetimes.ValueChangedFcn = createCallbackFcn(app, @checkbox_savetimes_Callback, true);
            app.checkbox_savetimes.Text = 'Save Times';
            app.edit_ltpnumber = uieditfield(app.uibuttongroup4, 'text');
            app.edit_ltpnumber.Value = 'ltp001';
            app.text8 = uilabel(app.uibuttongroup4);
            app.text8.Text = {'type ltp number if'; 'more than one sum'; 'in the same folder'};
            app.OnlyworksonchannelandtrialselectedinZebraLabel = uilabel(app.uibuttongroup4);
            app.OnlyworksonchannelandtrialselectedinZebraLabel.Text = {'It only works for the channel and the trial'; 'selected in ZebraExplore main window,'; 'for which events have been extracted.'};
            app.CopyTableButton = uibutton(app.uibuttongroup4, 'push');
            app.CopyTableButton.ButtonPushedFcn = createCallbackFcn(app, @CopyTableButtonPushed, true);
            app.CopyTableButton.Text = 'Copy Table ';
            app.RasterplotPanel = uipanel(app.figure1);
            app.RasterplotPanel.Title = 'Raster plot';
            app.rasterPlot_bt = uibutton(app.RasterplotPanel, 'push');
            app.rasterPlot_bt.ButtonPushedFcn = createCallbackFcn(app, @rasterPlot_Callback, true);
            app.rasterPlot_bt.Text = 'Raster plot';
            app.KernelbandwidthEditFieldLabel = uilabel(app.RasterplotPanel);
            app.KernelbandwidthEditFieldLabel.Text = 'Kernel bandwidth';
            app.kernelBW_edit = uieditfield(app.RasterplotPanel, 'numeric');
            app.kernelBW_edit.Value = 150;
            app.detectionPlot_ck = uicheckbox(app.RasterplotPanel);
            app.detectionPlot_ck.Text = 'Show detection plot';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleUnits_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)singleUnits_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end